classdef UI_EyeTracking < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        StoricoTable           matlab.ui.control.Table
        AnalizzaTrendButton    matlab.ui.control.Button
        PazienteDropDown       matlab.ui.control.DropDown
        PazienteDropDownLabel  matlab.ui.control.Label
        UIAxes                 matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Database_finale % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % 1. Carico il file dal disco dentro la proprietà dell'app
dati_salvati = load('Database_eyetracking.mat');
app.Database_finale = dati_salvati.Database_finale;

% 2. Estraggo gli ID dei pazienti e li inserisco nel DropDown
pazienti_univoci = unique(app.Database_finale.ID_Paziente);
app.PazienteDropDown.Items = string(pazienti_univoci);
        end

        % Button pushed function: AnalizzaTrendButton
        function AnalizzaTrendButtonPushed(app, event)
           % 1. Legge il valore selezionato nel DropDown come testo
valore_selezionato = string(app.PazienteDropDown.Value);

% 2. Filtra il database per il paziente scelto
Filtro = string(app.Database_finale.ID_Paziente) == valore_selezionato;
Dati_Paziente = app.Database_finale(Filtro, :);

% 3. Avviso se il filtro non trova nulla (es. dati mancanti)
if isempty(Dati_Paziente)
    uialert(app.UIFigure, 'Nessun dato trovato per il paziente selezionato.', 'Attenzione');
    return;
end

% 4. GRAFICO RISOLTO: Creiamo un asse X cronologico (1, 2, 3...)
numero_acquisizioni = 1:height(Dati_Paziente);
plot(app.UIAxes, numero_acquisizioni, Dati_Paziente.Velocita_Picco_deg_s, '-o', ...
     'LineWidth', 2, 'MarkerFaceColor', '#0072BD');
title(app.UIAxes, "Trend Velocità - Paziente " + valore_selezionato);
xlabel(app.UIAxes, "Numero Acquisizione (Ordine Cronologico)");
ylabel(app.UIAxes, "Velocità di Picco (deg/s)");
grid(app.UIAxes, 'on');

% 5. TABELLA RISOLTA: Inserimento diretto senza conversioni
app.StoricoTable.Data = Dati_Paziente;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'TrendAxes')
            xlabel(app.UIAxes, 'ID_Acquisizione')
            ylabel(app.UIAxes, 'Velocità di Picco (deg/s)')
            zlabel(app.UIAxes, 'Z')
            app.PazienteDropDownLabel = uilabel(app.UIFigure);
            app.PazienteDropDownLabel.Text = 'PazienteDropDown';
            app.PazienteDropDown = uidropdown(app.UIFigure);
            app.AnalizzaTrendButton = uibutton(app.UIFigure, 'push');
            app.AnalizzaTrendButton.ButtonPushedFcn = createCallbackFcn(app, @AnalizzaTrendButtonPushed, true);
            app.AnalizzaTrendButton.Text = 'Analizza Trend';
            app.StoricoTable = uitable(app.UIFigure);
            app.StoricoTable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.StoricoTable.RowName = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = UI_EyeTracking

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end